classdef realTimeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuroARTUIFigure              matlab.ui.Figure
        GroupIDDropDown               matlab.ui.control.DropDown
        GroupIDDropDownLabel          matlab.ui.control.Label
        AdjustwindowsizePanel         matlab.ui.container.Panel
        ApplyButton                   matlab.ui.control.Button
        UseacumulativewindowCheckBox  matlab.ui.control.CheckBox
        CorrelationAnalysisWindowsecondsSlider  matlab.ui.control.Slider
        CorrelationAnalysisWindowsecondsSliderLabel  matlab.ui.control.Label
        kHzLabel                      matlab.ui.control.Label
        FrameNoEditField              matlab.ui.control.NumericEditField
        FrameNoEditFieldLabel         matlab.ui.control.Label
        StimulateButton               matlab.ui.control.Button
        StartRealTimeAnalysisButton   matlab.ui.control.Button
        TuningAnalysisCheckBox        matlab.ui.control.CheckBox
        RedChannelCheckBox            matlab.ui.control.CheckBox
        CellRankings1Red2Green3BlueSelectedcellsYellowMagentaCyanLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        RealTimeAnalysisTab           matlab.ui.container.Tab
        Panel_5                       matlab.ui.container.Panel
        LoadButton_2                  matlab.ui.control.Button
        UITable_2                     matlab.ui.control.Table
        Panel_2                       matlab.ui.container.Panel
        LoadButton                    matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        Panel_3                       matlab.ui.container.Panel
        UIAxes_selected               matlab.ui.control.UIAxes
        Panel_4                       matlab.ui.container.Panel
        UIAxes_best                   matlab.ui.control.UIAxes
        StimulationTab                matlab.ui.container.Tab
        DelaymsEditField              matlab.ui.control.NumericEditField
        DelaymsEditFieldLabel         matlab.ui.control.Label
        PixelsLabel_2                 matlab.ui.control.Label
        PixelsLabel                   matlab.ui.control.Label
        YShiftEditField               matlab.ui.control.NumericEditField
        YShiftEditFieldLabel          matlab.ui.control.Label
        XShiftEditField               matlab.ui.control.NumericEditField
        XShiftEditFieldLabel          matlab.ui.control.Label
        PhaseMaskCenteringLabel       matlab.ui.control.Label
        VirtualLensEditField          matlab.ui.control.NumericEditField
        VirtualLensEditFieldLabel     matlab.ui.control.Label
        GSIterationsEditField         matlab.ui.control.NumericEditField
        GSIterationsEditFieldLabel    matlab.ui.control.Label
        VortexModeEditField           matlab.ui.control.NumericEditField
        VortexModeEditFieldLabel      matlab.ui.control.Label
        UIAxes8_2                     matlab.ui.control.UIAxes
        UIAxes8                       matlab.ui.control.UIAxes
        UIAxes7                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public) 
        imTemplate
        coordinates
        DFF 
        npBWout 
        rawFluo 
        roiBW2
        xcRaw 
        ycRaw 
        norm_meanIMG
        symmFLAG
        smoothwin
        r
        dffwindow
        percent
        last_init_frame
        displayWin
        batch_size 
        rank1
        rank2
        rank3
        select1
        select2
        select3
        selectedCellList
        selectedCellsAll
        selectedCellFrameID
        selectQueue
        hidden
        gap
        exptId
        exptVars
        minNpSubFluo
        maxAdjF
        numInitFrames
        mst
        mstWin
        numAvailFrames
        imagingFreq
        currentFr
        thorSyncFile
        psignalFile
        rawFileHandle
        greenChannel
        numChannels
        scope
        imgType
        ImageFile
        JiSignalInfo
        norm_meanRedIMG
        redChanDisplayed
        meanIMGhandle
        tstack
        xx
        yy
        selectedID
        loadCorrelated
        loadSelected
        groupId
        dll_name
        board_number
        SLM_ON
    end
    
    methods (Access = private)
        
        function [x,y] = selectDataPoints(app)
            roi = drawpoint(app.UIAxes,'Visible',"off");
            x = roi.Position(1);
            y = roi.Position(2);
        end
        
        
        function ImageClickCallback(app)
%             axesHandle  = get(objectHandle,'Parent');
            c = get(app.UIAxes,'CurrentPoint');
            x = c(1,1);
            y = c(1,2);
%             [x, y] = selectDataPoints(app);
            cdiff = app.coordinates - [x y];
            [~,ind] = min(sum(cdiff.^2,2));
            app.selectedID = ind;
%                 if ~ismember(ind,app.selectedCellList)
%                     app.groupId(ind) = str2double(app.GroupIDDropDown.Value);
%                     app.selectedCellList = [app.selectedCellList ind];
%                     app.selectedCellsAll = [app.selectedCellsAll ind];
%                     app.selectedCellFrameID = [app.selectedCellFrameID app.currentFr];
%                     set(app.selected(ind),'XData',app.xx(:,ind),'YData',app.yy(:,ind),'Color', colors(app.groupId(ind)));
%                 else
%                     app.selectedCellList = setdiff(app.selectedCellList,ind);
%                     set(app.selected(ind),'XData',NaN,'YData',NaN);
%                     app.hidden = ind;
%                 end
%                 if ind ~= app.select1 && ind ~= app.select2 && ind ~= app.select3 
%                     if app.selectQueue(1) == 1
%                         app.select1 = ind;
%                     elseif app.selectQueue(1) == 2
%                         app.select2 = ind;
%                     else
%                         app.select3 = ind;
%                     end  
%                     app.selectQueue = [app.selectQueue(2:end) app.selectQueue(1)];
%                     app.selectedCellList = [app.selectedCellList ind];
%                     app.selectedCellsAll = [app.selectedCellsAll ind];
%                     app.selectedCellFrameID = [app.selectedCellFrameID app.currentFr];
%                 end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imTemplate, DFF, npBWout, rawFluo, roiBW2, xcRaw, ycRaw, norm_meanIMG, symmFLAG, smoothwin, r, dffwindow, percent, last_init_frame, displayWin, batch_size, gap, exptId, exptVars, minNpSubFluo, maxAdjF, numInitFrames, mst, mstWin, numAvailFrames, imagingFreq, thorSyncFile, psignalFile, rawFileHandle, greenChannel, numChannels, scope, imgType, ImageFile, JiSignalInfo, norm_meanRedIMG, tstack, dll_name, board_number, SLM_ON)
            
            axis(app.UIAxes7,'off');
            app.kHzLabel.Visible = 'off';
            app.imTemplate = imTemplate;
            app.DFF = DFF;
            app.npBWout = npBWout; 
            app.rawFluo =  rawFluo;
            app.roiBW2 = roiBW2;
            app.xcRaw = xcRaw;
            app.ycRaw = ycRaw; 
            app.coordinates = [xcRaw ycRaw];
            app.norm_meanIMG = norm_meanIMG;
            app.norm_meanRedIMG = norm_meanRedIMG;
            app.symmFLAG = symmFLAG;
            app.smoothwin = smoothwin;
            app.r = r;
            app.dffwindow = dffwindow;
            app.percent = percent;
            app.last_init_frame = last_init_frame;
            app.currentFr = last_init_frame;
            app.displayWin = displayWin;
            app.batch_size = batch_size;
            app.rank1 = 0;
            app.rank2 = 0; 
            app.rank3 = 0;
            app.select1 = 0;
            app.select2 = 0;
            app.select3 = 0;
            app.selectedCellList = [];
            app.selectedCellsAll = [];
            app.selectedCellFrameID = [];
            app.hidden = [];
            app.gap = gap;
            app.exptId = exptId;
            app.exptVars = exptVars;
            app.minNpSubFluo = minNpSubFluo;
            app.maxAdjF = maxAdjF;
            app.numInitFrames = numInitFrames;
            app.mst = mst;
            app.mstWin = mstWin;
            app.numAvailFrames = numAvailFrames;
            app.thorSyncFile = thorSyncFile;
            app.psignalFile = psignalFile;
            app.imagingFreq = imagingFreq;
            app.rawFileHandle = rawFileHandle;
            app.greenChannel = greenChannel;
            app.numChannels = numChannels;
            app.scope = scope;
            app.imgType = imgType;
            app.ImageFile = ImageFile;
            app.UseacumulativewindowCheckBox.Value = ~mstWin;
            app.selectQueue = [1 2 3]; % First element of this array is the location to put the next selected DF/F trace
            app.JiSignalInfo = JiSignalInfo;
            app.redChanDisplayed = 0;
            app.meanIMGhandle = [];
            app.tstack = tstack;
            app.xx = [];
            app.yy = [];
            app.selectedID = 0;
            app.loadCorrelated = 0;
            app.loadSelected = 0;
            app.dll_name = dll_name;
            app.board_number = board_number;
            app.SLM_ON = SLM_ON;
%             app.h2 = [];
            % Load mean image
            imshow(app.norm_meanIMG,'Parent', app.UIAxes);
            hold(app.UIAxes,'on');
%             axis(app.UIAxes_selected, 'tight');
        end

        % Button pushed function: StartRealTimeAnalysisButton
        function StartRealTimeAnalysisButtonPushed(app, event)
            totStart = tic;
            addpath(genpath('utilities'));
            addpath(genpath('Psignal'));
            warning off;
            
            app.meanIMGhandle = imshow(app.norm_meanIMG,'Parent', app.UIAxes);
            set(app.meanIMGhandle,'ButtonDownFcn',@(h,e) ImageClickCallback(app));
            set(app.meanIMGhandle, 'PickableParts', 'all');
            hold(app.UIAxes,'on');
            
%             set(app.UIAxes,'DrawMode', 'fast');
%             set(app.UIAxes_best,'DrawMode', 'fast');
%             set(app.UIAxes_selected,'DrawMode', 'fast');
            
            nROIs = length(app.xcRaw);
            DFFsmooth = app.DFF.smooth;
            
            dimX = app.exptVars.dimX;
            dimY = app.exptVars.dimY;

            T = 1/app.exptVars.frameRate;
            app.groupId = zeros(nROIs,1);
            colors = ['y', 'm', 'c'];
            
            A = max(DFFsmooth,[],2);
            B = sort(A,'ascend');
            upperLim = B(ceil(nROIs*0.7))*1.25; % 0.9 problem with NaN values
            upperLim_patch = B(ceil(nROIs*0.7));
            A = min(DFFsmooth,[],2);
            B = sort(A,'descend');
            lowerLim = B(ceil(nROIs*0.9))*1.25;
            lowerLim_patch = B(ceil(nROIs*0.7));
           
            
            DFFsmooth = horzcat(DFFsmooth,zeros(nROIs,app.batch_size));
            app.rawFluo = horzcat(app.rawFluo,zeros(nROIs,app.batch_size));
            
            offSet = (upperLim-lowerLim)/2;

            x_val = 1:app.displayWin;
            
            nRanks = 5; % number of top ranks to be displayed
            
            if nROIs < 150 && nROIs >= nRanks
                h1 = plot(app.UIAxes_best,x_val,NaN(app.displayWin,nRanks));
                set(h1, {'color'}, {'r'; 'g'; 'b'; 'k'; 'k'});
            else
                nRanks = 3;
                h1 = plot(app.UIAxes_best,x_val,NaN(app.displayWin,nRanks));
                set(h1, {'color'}, {'r'; 'g'; 'b'});
            end
            
            xlim(app.UIAxes_best,[1 app.displayWin]); ylim(app.UIAxes_best,[lowerLim,lowerLim + (nRanks+1)*offSet]);
            h2 = plot(app.UIAxes_selected,x_val,NaN(app.displayWin,nROIs),'k');
            xlim(app.UIAxes_selected,[1 app.displayWin]); ylim(app.UIAxes_selected,[lowerLim,lowerLim + 11*offSet]);
            
            app.UIAxes_selected.Position = [app.UIAxes_selected.Position(1:2) [app.Panel_3.Position(3:4) - 2*app.UIAxes_selected.Position(1:2)]];
            
%             set(h1, {'XData'}, num2cell(repmat(x_val,1,5),2));
%             set(h2, {'XData'}, num2cell(repmat(x_val,1,nROIs),2));

%             t1 = zeros(app.batch_size,1);
%             t2 = zeros(app.batch_size,1);
%             t3 = zeros(app.batch_size,1);
%             t4 = zeros(app.batch_size,1);

            nr = floor((app.batch_size - app.last_init_frame)/app.gap);
            cellRanks = zeros(min(nROIs,nRanks),nr); % cellRanks = zeros(nROIs,1);
%             cellRanks_clean = zeros(min(nROIs,nRanks),nr); 
%             cellRank = ones(min(nROIs,nRanks),1); % cellRank = ones(nROIs,1);
            allRanks = zeros(nROIs,nr);
            
%             ranks = zeros(nRanks,1); %Ranks 1 to 3 or 5
            
            ntheta = 20;
            theta = linspace(0, 2*pi, ntheta)'; % Generate circular patches around the centroids of identified neurons
            app.xx = bsxfun(@plus, app.r .* cos(theta), app.xcRaw');
            app.yy = bsxfun(@plus, app.r .* sin(theta), app.ycRaw');
%             ptc = patch(app.UIAxes, xx, yy, (app.rawFluo(:,app.numInitFrames)-lowerLim)./upperLim);

            ptc = patch(app.UIAxes, app.xx, app.yy, zeros(1,nROIs,3),'ButtonDownFcn',@(h,e) ImageClickCallback(app));
            
            cc = []; % color code
            
            neuron(1) = plot(app.UIAxes,NaN,NaN,'Color','r','LineWidth',2,'ButtonDownFcn',@(h,e) ImageClickCallback(app));
            neuron(2) = plot(app.UIAxes,NaN,NaN,'Color','g','LineWidth',2,'ButtonDownFcn',@(h,e) ImageClickCallback(app));
            neuron(3) = plot(app.UIAxes,NaN,NaN,'Color','b','LineWidth',2,'ButtonDownFcn',@(h,e) ImageClickCallback(app));
            
            if nRanks == 5
                neuron(4) = plot(app.UIAxes,NaN,NaN,'Color','k','LineWidth',2,'ButtonDownFcn',@(h,e) ImageClickCallback(app));
                neuron(5) = plot(app.UIAxes,NaN,NaN,'Color','k','LineWidth',2,'ButtonDownFcn',@(h,e) ImageClickCallback(app));
            end
            
            selected = plot(app.UIAxes,1:ntheta,NaN(ntheta,nROIs),'Color','y','LineWidth',2,'ButtonDownFcn',@(h,e) ImageClickCallback(app));
            xlim(app.UIAxes,[1 dimX]); ylim(app.UIAxes,[1 dimY]);
            
%             for itr = 1:nROIs
%                 app.selected(itr) = plot(app.UIAxes,NaN,NaN,'Color','y','LineWidth',2,'ButtonDownFcn',@(h,e) ImageClickCallback(app));
%             end
%             selected1 = plot(app.UIAxes,NaN,NaN,'Color','y','LineWidth',2);
%             selected2 = plot(app.UIAxes,NaN,NaN,'Color','y','LineWidth',2);
%             selected3 = plot(app.UIAxes,NaN,NaN,'Color','y','LineWidth',2);

%             txt(1) = text(app.UIAxes,NaN,NaN,'\leftarrow 1', 'Color','w','ButtonDownFcn',@(h,e) ImageClickCallback(app));
%             txt(2) = text(app.UIAxes,NaN,NaN,'\leftarrow 2', 'Color','w','ButtonDownFcn',@(h,e) ImageClickCallback(app));
%             txt(3) = text(app.UIAxes,NaN,NaN,'\leftarrow 3', 'Color','w','ButtonDownFcn',@(h,e) ImageClickCallback(app));
%             txt(4) = text(app.UIAxes,NaN,NaN,'\leftarrow 4', 'Color','w','ButtonDownFcn',@(h,e) ImageClickCallback(app));
%             txt(5) = text(app.UIAxes,NaN,NaN,'\leftarrow 5', 'Color','w','ButtonDownFcn',@(h,e) ImageClickCallback(app));

            i = 1;
            
            cb = colorbar(app.UIAxes7);
            cb.Visible = 'off';
            colorbarCreated = 0;
            
            
            frameSize = dimX*dimY;
            itr = app.numChannels*app.last_init_frame;
            offset = 2*frameSize*app.numChannels*app.last_init_frame;
            fh = app.rawFileHandle;
            tX = [];
            tY = [];
            timing = zeros(10,1);
            
            count = 1;
            timing(1) = toc(totStart);
            
            
            delete(findall(app.UIAxes,'Type','hggroup')); % remove tooltip text
                
            
            while i <= app.batch_size
                
                if i == 3000
                    timing(2) = toc(totStart);
                end
                
                app.currentFr = app.last_init_frame + i;
                
                if (app.scope == 1) || (app.scope == 2) || (app.scope == 3) || (app.scope == 6 && app.imgType == ".raw")
                
                    fseek(fh,0,'eof');
                    numFramesRecorded = floor(ftell(fh)/(frameSize*2));
        
                    if numFramesRecorded > itr % detection of a new frame, assuming the raw file isn't fully preallocated
                        if app.numChannels==1 || mod(itr+1,app.numChannels) == app.greenChannel || (mod(itr+1,app.numChannels) == 0 && app.greenChannel == app.numChannels) % Only read if it belongs to the Green Channel
                            fseek(fh,offset,'bof');
                            currentIMG = reshape(fread(fh, frameSize, 'uint16=>uint16'),[dimX dimY]); % Read the (itr+1)th frame
%                             currentIMG = currentIMG'; %DZ 051722, for scanimage data
                            count = 1;
                            itr = itr + 1;
                            offset = 2*itr*frameSize; % uint16 --> 2 bytes
                        else
                            count = 1;
                            itr = itr + 1;
                            offset = 2*itr*frameSize; % uint16 --> 2 bytes
                            pause(0.002);
                            continue;
                        end
                    else
                        count = count + 1;
                        if count == 3000 % wait for 1 min
                            disp('No frame was generated in allowed time');
                            count = -1;
                        else
                            pause(0.002);
                            continue;
                        end
                    end
                    
                elseif (app.scope == 2 && app.imgType == ".tif") % Only valid if Bruker spits out tif file sequences with proper indexing
                    [filepath,name,ext] = fileparts(app.ImageFile);
                    fName = fullfile(filepath, [name '_Ch' num2str(app.greenChannel) '_' sprintf('%05d',app.currentFr) '.ome' ext]); % Format of the Bruker tif filenames 
             
                    if isfile(fName) % detection of a new frame
                        currentIMG = imread(fName); % Read the new frame
                        count = 1;
                    else
                        count = count + 1;
                        if count == 3000 % wait for 1 min
                            disp('No frame was generated in allowed time');
                            count = -1;
                        else
                            pause(0.002);
                            continue;
                        end
                    end
                    
                elseif (app.scope == 6 && app.imgType == ".tif")
                    warning('off','all') % Suppress all the tiff warnings
                    
%                     if itr == app.numChannels*app.last_init_frame
%                         disp('init');
%                         app.tstack  = Tiff(app.ImageFile);
%                         app.tstack.read();
%                         for fr = 2:itr
%                             app.tstack.nextDirectory();
%                         end
%                     end
                    
                    numFramesRecorded = 38400; %length(imfinfo(app.ImageFile)) % imfinfo takes around 2s
        
                    if numFramesRecorded > itr % detection of a new frame, assuming the raw file isn't fully preallocated
                      
                        app.tstack.nextDirectory();
                        if app.numChannels==1 || mod(itr+1,app.numChannels) == app.greenChannel || (mod(itr+1,app.numChannels) == 0 && app.greenChannel == app.numChannels) % Only read if it belongs to the Green Channel
%                             data = [];
%                             tstack.read();
%                             for n = 2:itr+1
%                                 tstack.nextDirectory()
%                                 if n == itr+1
%                                     data = tstack.read();
%                                 end
%                             end
%                             currentIMG = data;
                            
                            currentIMG = app.tstack.read();
                            count = 1;
                            itr = itr + 1;
                        else
                            count = 1;
                            itr = itr + 1;
                            pause(0.002);
                            continue;
                        end
                    else
                        count = count + 1;
                        if count == 3000 % wait for 1 min
                            disp('No frame was generated in allowed time');
                            count = -1;
                        else
                            pause(0.002);
                            continue;
                        end
                    end
                    
                else
                    count = -1;
                end
                
                if count == -1 && i == 1
                    close(gcf);
                    break;
                elseif count == -1 && i > 1
                    
%                     for itr = 1:min(nROIs,nRanks)
%                         ranks(itr) = mode(cellRanks(itr,:));
%                     end
                    
%                     selectedCells = [app.select1 app.select2 app.select3];
                    app.selectedCellList = unique(app.selectedCellList); % remove duplicates
                    hiddenCells = app.hidden;
%                     assignin("base","ranks",ranks);
                    assignin("base","selectedCellList",app.selectedCellList);
                    assignin("base","selectedCellsAll",app.selectedCellsAll);
                    assignin("base","selectedCellFrameID",app.selectedCellFrameID);
                    assignin("base","hiddenCells",hiddenCells)
                    assignin("base","DFFsmooth",DFFsmooth);
                    assignin("base","cellRanks",cellRanks);
%                     assignin("base","cellRanks_clean",cellRanks_clean);
                    assignin("base","allRanks",allRanks);
                    assignin("base","F",app.rawFluo);
                    assignin("base","mstWin_new",app.mstWin);
                    evalin('base', ['save(''', app.exptId ''')']);
                    
                    if colorbarCreated
                        assignin("base","tuningInfo",tuningInfo);
                    end
                    
                    promptMessage = sprintf('Save TIFs with labelled cells? (Optional)');
                    button = questdlg(promptMessage, 'Real-time analysis completed', 'Yes', 'No', 'Yes');
                    if strcmpi(button, 'No')
                        close(app.NeuroARTUIFigure); % DZ 030522
                        return; % Or break or continue
                    else
                        lastFrame = saveLabelledNeurons(cellRanks(:,2:end),app.selectedCellList,hiddenCells,app.rawFluo,app.xcRaw,app.ycRaw,app.norm_meanIMG,app.numInitFrames,app.r,app.gap,app.exptId);
                    end
                    
                    promptMessage = sprintf('Images saved successfully!');
                    msgbox(promptMessage,'Success','help');
                    
                    pause(3);
                    close(app.NeuroARTUIFigure); % DZ 030522
                    
                    promptMessage = sprintf('Do you want to manually select ROIs?');
                    button = questdlg(promptMessage, 'Real-time analysis completed', 'Yes', 'No', 'Yes');
                    if strcmpi(button, 'No')
                        return; % Or break or continue
                    else
                        showFrame(lastFrame);
                    end
                
                    break
                end
    
%                 iStart = tic;
    
%                 currentIMG = imread(filename); % read tiff image
                
%                 fh = fopen(filename); % read raw image
%                 currentIMG = reshape(fread(fh,inf,'uint16=>uint16'),[app.exptVars.dimX, app.exptVars.dimY]);
%                 currentIMG  = currentIMG';
%                 fclose(fh);
    
    
                %% Register the current frame (Using Sam's motion correction code)
    
                [currentRegIMG, ~, ~] = regseqRT(app.imTemplate,currentIMG);
%                 tX = [tX;tx];
%                 tY = [tY;ty];

                if i == 3000
                    timing(3) = toc(totStart);
                end
    
                % Compute DF/F of the current frame 
                
%                 [newDFF, rawFluoNew,~,~,~,~] = RTcomputeDFF(app.roiBW2,app.npBWout,currentRegIMG,app.dffwindow,app.percent, app.rawFluo(:,end-app.dffwindow:end), T, app.symmFLAG, app.smoothwin,app.minNpSubFluo,app.maxAdjF); %return updated DFF trace
    
                [newDFF, rawFluoNew,~,~,~,~] = RTcomputeDFF(app.roiBW2,app.npBWout,currentRegIMG,app.dffwindow,app.percent, app.rawFluo(:,app.currentFr-1-app.dffwindow:app.currentFr-1), T, app.symmFLAG, app.smoothwin,app.minNpSubFluo,app.maxAdjF); %return updated DFF trace
                
                if i == 3000
                    timing(4) = toc(totStart);
                end
                
                % append new DFF and raw fluorescence value
                
%                 DFFsmooth = horzcat(DFFsmooth(:,1:end-app.smoothwin+1),newDFF);
%                 app.rawFluo = horzcat(app.rawFluo(:,1:end-app.smoothwin+1),rawFluoNew);
    
                DFFsmooth(:,app.currentFr-app.smoothwin+1:app.currentFr) = newDFF;
                app.rawFluo(:,app.currentFr-app.smoothwin+1:app.currentFr) = rawFluoNew;
    
                %% Minimum spanning tree / correlation based approach to find the most population-correlated cells
                %% update grayscale patches to indicate DF/F
    
                %% Indicate the most critical cells for excitation --> Top 3: green,magenta,blue 4,5: blue (update the figure every 30 frames)
    
                if app.currentFr > app.numAvailFrames && mod(i,app.gap)==0 && i>app.smoothwin % update every 15 frames (if app.gap = 15), avoid displaying frames that were acquired during cell finding
                    
                    
                    app.FrameNoEditField.Value = app.currentFr;
                    
                    if i == 3000
                        timing(5) = toc(totStart);
                    end
                    
                    if (~app.mstWin)
                        cellRank = selectCorrNeuron(DFFsmooth(:,1:app.currentFr-app.smoothwin+2),app.mst); %cumulative
                    else
                        cellRank = selectCorrNeuron(DFFsmooth(:,app.currentFr-app.smoothwin+3-app.mstWin:app.currentFr-app.smoothwin+2),app.mst); % sliding window  
                    end
                    
                    allRanks(:,i/app.gap) = cellRank;
                                       
                    numElements = min(nROIs,nRanks);
                    cellRanks(:,i/app.gap) = cellRank(1:numElements); % Each column contains ids of top 5 ranked cells during each GUI update

                    cellRank = cellRank(1:numElements);
%                     cellRank = setdiff(cellRank,app.hidden,'stable'); % remove bad ROIs
% 
%                     if length(cellRank)>=numElements
%                         cellRank = cellRank(1:numElements); % top 5 ranks
%                         cellRanks_clean(:,i/app.gap) = cellRank;
%                     else
%                         cellRanks_clean(:,i/app.gap) = vertcat(cellRank, zeros(numElements - length(cellRank),1));
%                     end
                    
%                     else
%                         cellRanks = horzcat(cellRanks,vertcat(cellRank, zeros(nRanks - nROIs,1)));
%                         cellRank = setdiff(cellRank,app.hidden,'stable'); % remove bad ROIs
%                         cellRanks_clean = horzcat(cellRanks_clean,vertcat(cellRank, zeros(nRanks - length(cellRank),1)));
%                     end    
                    
                    if i == 3000
                        timing(6) = toc(totStart);
                    end
  
                    if app.TuningAnalysisCheckBox.Value % Run this if the tuning analysis checkbox is selected
                        
                        if mod(i,30*app.gap)==0
                            
%                             if app.redChanDisplayed && app.RedChannelCheckBox.Value == 0 % red channel check box unchecked again
%                                 ptc = patch(app.UIAxes, xx, yy, zeros(1,nROIs,3));
%                                 app.redChanDisplayed = 0;
%                             end
                            
                            if isempty(app.JiSignalInfo)
                                tuningInfo = getTuningRealTimeFunction(DFFsmooth(:,1:app.currentFr-app.smoothwin+2),app.exptVars,app.psignalFile,app.thorSyncFile);
                            else
                                tuningInfo = getTuningRealTimeFunction_Ji(DFFsmooth(:,1:app.currentFr-app.smoothwin+2),app.exptVars,app.JiSignalInfo);
                            end
                            if isempty(cc)
                                cc(1,:,:) = jet(numel(tuningInfo.kHzVals));
                            end
                            
                            set(ptc,'CData',cc(:,tuningInfo.BFval,:));
                            
                            if ~colorbarCreated
                                cc1 = jet(numel(tuningInfo.kHzVals));
                                colormap(app.UIAxes7,cc1)
                                stepsize = 1/numel(tuningInfo.kHzVals);
                                cb.Ticks = stepsize/2:stepsize:1-stepsize/2;
                                cb.TickLabels = tuningInfo.kHzVals;
                                colorbarCreated = 1;
                                
                            end
                            app.kHzLabel.Visible = 'on';
                            cb.Visible = 'on';
                            
                        end
                        
                    else
%                         if app.redChanDisplayed && app.RedChannelCheckBox.Value == 0 % red channel check box unchecked again
%                             ptc = patch(app.UIAxes, xx, yy, zeros(1,nROIs,3));
%                             app.redChanDisplayed = 0;
%                         end

                        cb.Visible = 'off';
                        app.kHzLabel.Visible = 'off';
                        set(ptc,'CData',(newDFF(:,3)-lowerLim_patch)./upperLim_patch); % update DF/F values in grayscale patches
%                         set(ptc,'CData',(app.rawFluo(:,app.numInitFrames+i)-lowerLim)./upperLim);
                    end
                    
                    % DZ 030522
                    % cellRank --> column vector of top ranked cell ids. length could be < 5 (because of removing bad ROIs)
                    
                    for idx = 1:length(cellRank)
                        set(neuron(idx),'XData',app.xx(:,cellRank(idx)),'YData',app.yy(:,cellRank(idx)));
%                         set(txt(itr),'Position',[app.xcRaw(cellRank(itr)),app.ycRaw(cellRank(itr))]);
                    end
                    
                    if app.selectedID
                        if ~ismember(app.selectedID,app.selectedCellList)
                            app.groupId(app.selectedID) = str2double(app.GroupIDDropDown.Value);
                            app.selectedCellList = [app.selectedCellList app.selectedID];
                            app.selectedCellsAll = [app.selectedCellsAll app.selectedID];
                            app.selectedCellFrameID = [app.selectedCellFrameID app.currentFr];
                            set(selected(app.selectedID),'XData',app.xx(:,app.selectedID),'YData',app.yy(:,app.selectedID),'Color', colors(app.groupId(app.selectedID)));
                        else
                            app.selectedCellList = setdiff(app.selectedCellList,app.selectedID);
                            set(selected(app.selectedID),'XData',NaN,'YData',NaN);
                            app.hidden = app.selectedID;
                            app.groupId(app.selectedID) = 0;
                        end
                        app.selectedID = 0; 
                    end
                    
                    if app.loadCorrelated % load x, y coordinates of top ranked cells
                        xc = app.xcRaw(cellRank);
                        yc = app.ycRaw(cellRank);
                        group = ones(length(cellRank),1);
                        app.UITable.Data = table(xc,yc,group);
                        app.loadCorrelated = 0;
                    end
                    
                    if app.loadSelected % load x, y coordinates of the selected cells
                        xc = app.xcRaw(app.selectedCellList);
                        yc = app.ycRaw(app.selectedCellList);
                        group = app.groupId(app.selectedCellList);
                        app.UITable_2.Data = table(xc,yc,group);
                        app.loadSelected = 0;
                    end
                    
                    if i == 3000
                        timing(7) = toc(totStart);
                    end
                    
%                     if ~isempty(app.selectedCellList)
%                         for itr = 1:length(app.selectedCellList)
%                             set(selected(itr),'XData',xx(:,app.selectedCellList(itr)),'YData',yy(:,app.selectedCellList(itr)));
%                         end
%                     end
                    
%                     cellRank = sort(cellRank(1:3)); % sort in the ascending order (to minimize abrupt changes in DF/F plot locations)
                    
%                     cellRank = cellRank(1:3);
%                     prev = [app.rank1;app.rank2;app.rank3]; % previous order of DF/F plots
%                     
%                     isCommon = ismember(prev,cellRank);
%                     replace = ~isCommon;
%                     
%                     prev(replace) = setdiff(cellRank,prev);      % keep the DF/F plots of common cells at the same location in the gui, only replace other plots with new ones
%                     
%                     cellRank = prev;
%                     
%                     app.rank1 = cellRank(1);
%                     app.rank2 = cellRank(2);
%                     app.rank3 = cellRank(3);
                    
                
%                     set(h1, 'YData', DFFsmooth(cellRank(1),end-app.smoothwin-app.displayWin+3:end-app.smoothwin+2));
%                     set(h2, 'YData', DFFsmooth(cellRank(2),end-app.smoothwin-app.displayWin+3:end-app.smoothwin+2));
%                   
                    for idx = 1:length(cellRank)
                        set(h1(idx), {'YData'}, num2cell(offSet*(idx-1) + DFFsmooth(cellRank(idx),app.currentFr-app.smoothwin-app.displayWin+3:app.currentFr-app.smoothwin+2),2));
                    end
                    
                    if i == 3000
                        timing(8) = toc(totStart);
                    end
                    
                    if ~isempty(app.selectedCellList)
                        for idx = 1:length(app.selectedCellList)
                            set(h2(app.selectedCellList(idx)), {'YData'}, num2cell(offSet*(mod(idx-1,10)) + DFFsmooth(app.selectedCellList(idx),app.currentFr-app.smoothwin-app.displayWin+3:app.currentFr-app.smoothwin+2),2));
                        end  
                    end
                    
                    if i == 3000
                        timing(9) = toc(totStart);
                    end
                    
                    % Remove the graph of the recently deselected cell
                    
                    if ~isempty(app.hidden)
                        set(h2(app.hidden),'YData', NaN);
                        app.hidden = [];
                    end
                                        
%                     set(h2, 'YData', DFFsmooth(cellRank(2),end-app.smoothwin-app.displayWin+3:end-app.smoothwin+2));
                     
                    drawnow limitrate; % update GUI figures and plots
%                     pause(0.1);
                    
                    if i == 3000
                        timing(10) = toc(totStart);
                    end
                    
                end            
    
%                 if i==app.batch_size
%                     for itr = 1:min(nROIs,nRanks)
%                         ranks(itr) = mode(cellRanks(itr,:)); % cellRanks_clean(itr,:)
%                     end
%                 end
    
                i = i + 1;
    
            end

            if i == 1
                f = app.NeuroARTUIFigure;
                mess = "No frame was generated in allowed time";
                title = "Missing real time input";    
                uialert(f, mess, title,'CloseFcn',@(h,e) close(f));
                return;
            end
            
            tstop = toc(totStart)
            timing
            
%             selectedCells = [app.select1 app.select2 app.select3];
            app.selectedCellList = unique(app.selectedCellList); % remove duplicates
            hiddenCells = app.hidden;
            
            assignin("base","selectedCellList",app.selectedCellList);
            assignin("base","selectedCellsAll",app.selectedCellsAll);
            assignin("base","selectedCellFrameID",app.selectedCellFrameID);
            assignin("base","hiddenCells",hiddenCells)
            assignin("base","DFFsmooth",DFFsmooth);
            assignin("base","cellRanks",cellRanks);
%             assignin("base","cellRanks_clean",cellRanks_clean);
            assignin("base","allRanks",allRanks);
            assignin("base","F",app.rawFluo);
            assignin("base","mstWin_new",app.mstWin);
            assignin("base","groupId",app.groupId);
            evalin('base', ['save(''', app.exptId ''')']);
            
            if colorbarCreated
                assignin("base","tuningInfo",tuningInfo);
            end
            
            promptMessage = sprintf('Save TIFs with labelled cells? (Optional)');
            button = questdlg(promptMessage, 'Real-time analysis completed', 'Yes', 'No', 'Yes');
            if strcmpi(button, 'Yes')
                lastFrame = saveLabelledNeurons(cellRanks(:,2:end),app.selectedCellList,hiddenCells,app.rawFluo,app.xcRaw,app.ycRaw,app.norm_meanIMG,app.numInitFrames,app.r,app.gap,app.exptId);
                promptMessage = sprintf('Images saved successfully!');
                msgbox(promptMessage,'Success','help');
            end
                    
            pause(5);
                    
            promptMessage = sprintf('Do you want to save tuning analysis data?');
            button = questdlg(promptMessage, 'Real-time analysis completed', 'Yes', 'No', 'Yes');
            if strcmpi(button, 'No')
                return; % Or break or continue
            else
%                 showFrame(lastFrame);
                [BFval, BFkHzVals] = saveTuningData(DFFsmooth,app.exptVars,app.psignalFile,app.thorSyncFile,app.JiSignalInfo, app.xcRaw,app.ycRaw,app.norm_meanIMG,app.numInitFrames,app.r,app.gap,app.exptId);
                assignin("base","BFval",BFval);
                assignin("base","BFkHzVals",BFkHzVals);
                promptMessage = sprintf('Images saved successfully!');
                msgbox(promptMessage,'Success','help');
            end
                
        end

        % Button pushed function: StimulateButton
        function StimulateButtonPushed(app, event)
            
            cellIDs = find(app.groupId == 1); % hard coded for now. change this to pick the selected groupID for stimulation
            target_cells = horzcat(app.xcRaw(cellIDs),app.ycRaw(cellIDs));
            
%             target_cells = unique(target_cells,'rows'); % remove duplicate entries
            stimParams.VMode = app.VortexModeEditField.Value;
            stimParams.VLens = app.VirtualLensEditField.Value;
            stimParams.GS = app.GSIterationsEditField.Value;
            stimParams.xShift = app.XShiftEditField.Value;
            stimParams.yShift = app.YShiftEditField.Value;
            stimParams.delay = app.DelaymsEditField.Value;
            stimParams.dll_name = app.dll_name;
            stimParams.board_number = app.board_number;
            
            assignin("base","target_cells",target_cells);
            writematrix(target_cells,'stimulationTargets.csv');
            if app.SLM_ON
                [msk,I] = stimulateBNS_SLM(target_cells, stimParams); % send commands to BNS SLM
                pause(0.5);
                stimParams.VLens = 0;
                [msk1,I1] = stimulateBNS_SLM(target_cells, stimParams);
                imshow(msk,'Parent', app.UIAxes8);
                imshow(I','Parent', app.UIAxes8_2);
            else
                disp('SLM not available');
            end
            evalin('base', ['save(''', 'targets_', app.exptId ''',', '''', 'target_cells' ''')']); % save the coordinates to a .mat file
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if app.UseacumulativewindowCheckBox.Value
                app.mstWin = 0; % The value that indicates cumulative window
            else
                wsize = floor(app.CorrelationAnalysisWindowsecondsSlider.Value *app.imagingFreq);
                if wsize < app.currentFr + 3 - app.smoothwin
                    app.mstWin = wsize;
                else
                    promptMessage = sprintf('Window size for correlation analysis exceeds the available number of frames');
                    msgbox(promptMessage,'Error','error');
                end
            end
        end

        % Value changed function: RedChannelCheckBox
        function RedChannelCheckBoxValueChanged(app, event)
            value = app.RedChannelCheckBox.Value;
            if value
               set(app.meanIMGhandle, 'CData', app.norm_meanRedIMG);
            else
               set(app.meanIMGhandle, 'CData', app.norm_meanIMG);
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.loadCorrelated = 1;
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            app.loadSelected = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeuroARTUIFigure = uifigure('Visible', 'off');
            app.NeuroARTUIFigure.Name = 'NeuroART';
            app.UIAxes = uiaxes(app.NeuroARTUIFigure);
            app.UIAxes.Visible = 'off';
            app.UIAxes7 = uiaxes(app.NeuroARTUIFigure);
            app.UIAxes7.XTick = [];
            app.UIAxes7.YTick = [];
            app.TabGroup = uitabgroup(app.NeuroARTUIFigure);
            app.RealTimeAnalysisTab = uitab(app.TabGroup);
            app.RealTimeAnalysisTab.Title = 'Real Time Analysis';
            app.Panel_4 = uipanel(app.RealTimeAnalysisTab);
            app.UIAxes_best = uiaxes(app.Panel_4);
            title(app.UIAxes_best, 'DF/F (Most correlated  cells)')
            app.UIAxes_best.YTick = [];
            app.Panel_3 = uipanel(app.RealTimeAnalysisTab);
            app.UIAxes_selected = uiaxes(app.Panel_3);
            title(app.UIAxes_selected, 'DF/F (Selected cells)')
            app.UIAxes_selected.YTick = [];
            app.Panel_2 = uipanel(app.RealTimeAnalysisTab);
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'X'; 'Y'; 'ID'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true];
            app.LoadButton = uibutton(app.Panel_2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Panel_5 = uipanel(app.RealTimeAnalysisTab);
            app.UITable_2 = uitable(app.Panel_5);
            app.UITable_2.ColumnName = {'X'; 'Y'; 'ID'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = [false false true];
            app.LoadButton_2 = uibutton(app.Panel_5, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.StimulationTab = uitab(app.TabGroup);
            app.StimulationTab.Title = 'Stimulation';
            app.UIAxes8 = uiaxes(app.StimulationTab);
            title(app.UIAxes8, 'Phase Mask')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes8.XTick = [];
            app.UIAxes8.YTick = [];
            app.UIAxes8_2 = uiaxes(app.StimulationTab);
            title(app.UIAxes8_2, 'Simulated Pattern')
            zlabel(app.UIAxes8_2, 'Z')
            app.UIAxes8_2.XTick = [];
            app.UIAxes8_2.YTick = [];
            app.VortexModeEditFieldLabel = uilabel(app.StimulationTab);
            app.VortexModeEditFieldLabel.Text = 'Vortex Mode';
            app.VortexModeEditField = uieditfield(app.StimulationTab, 'numeric');
            app.VortexModeEditField.Value = 5;
            app.GSIterationsEditFieldLabel = uilabel(app.StimulationTab);
            app.GSIterationsEditFieldLabel.Text = 'GS Iterations';
            app.GSIterationsEditField = uieditfield(app.StimulationTab, 'numeric');
            app.GSIterationsEditField.Value = 3;
            app.VirtualLensEditFieldLabel = uilabel(app.StimulationTab);
            app.VirtualLensEditFieldLabel.Text = 'Virtual Lens';
            app.VirtualLensEditField = uieditfield(app.StimulationTab, 'numeric');
            app.VirtualLensEditField.Value = 850;
            app.PhaseMaskCenteringLabel = uilabel(app.StimulationTab);
            app.PhaseMaskCenteringLabel.Text = 'Phase Mask Centering:';
            app.XShiftEditFieldLabel = uilabel(app.StimulationTab);
            app.XShiftEditFieldLabel.Text = 'XShift';
            app.XShiftEditField = uieditfield(app.StimulationTab, 'numeric');
            app.XShiftEditField.Value = 5;
            app.YShiftEditFieldLabel = uilabel(app.StimulationTab);
            app.YShiftEditFieldLabel.Text = 'YShift';
            app.YShiftEditField = uieditfield(app.StimulationTab, 'numeric');
            app.YShiftEditField.Value = 140;
            app.PixelsLabel = uilabel(app.StimulationTab);
            app.PixelsLabel.Text = 'Pixels';
            app.PixelsLabel_2 = uilabel(app.StimulationTab);
            app.PixelsLabel_2.Text = 'Pixels';
            app.DelaymsEditFieldLabel = uilabel(app.StimulationTab);
            app.DelaymsEditFieldLabel.Text = 'Delay (ms)';
            app.DelaymsEditField = uieditfield(app.StimulationTab, 'numeric');
            app.CellRankings1Red2Green3BlueSelectedcellsYellowMagentaCyanLabel = uilabel(app.NeuroARTUIFigure);
            app.CellRankings1Red2Green3BlueSelectedcellsYellowMagentaCyanLabel.Text = 'Cell Rankings: 1) Red 2) Green 3) Blue;   Selected cells - Yellow, Magenta, Cyan';
            app.RedChannelCheckBox = uicheckbox(app.NeuroARTUIFigure);
            app.RedChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedChannelCheckBoxValueChanged, true);
            app.RedChannelCheckBox.Text = 'Red Channel';
            app.TuningAnalysisCheckBox = uicheckbox(app.NeuroARTUIFigure);
            app.TuningAnalysisCheckBox.Text = 'Tuning Analysis';
            app.StartRealTimeAnalysisButton = uibutton(app.NeuroARTUIFigure, 'push');
            app.StartRealTimeAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartRealTimeAnalysisButtonPushed, true);
            app.StartRealTimeAnalysisButton.Text = 'Start Real Time Analysis';
            app.StimulateButton = uibutton(app.NeuroARTUIFigure, 'push');
            app.StimulateButton.ButtonPushedFcn = createCallbackFcn(app, @StimulateButtonPushed, true);
            app.StimulateButton.Text = 'Stimulate';
            app.FrameNoEditFieldLabel = uilabel(app.NeuroARTUIFigure);
            app.FrameNoEditFieldLabel.Text = 'Frame No:';
            app.FrameNoEditField = uieditfield(app.NeuroARTUIFigure, 'numeric');
            app.FrameNoEditField.ValueDisplayFormat = '%.0f';
            app.FrameNoEditField.Editable = 'off';
            app.kHzLabel = uilabel(app.NeuroARTUIFigure);
            app.kHzLabel.Text = 'kHz';
            app.AdjustwindowsizePanel = uipanel(app.NeuroARTUIFigure);
            app.AdjustwindowsizePanel.Title = 'Adjust window size';
            app.CorrelationAnalysisWindowsecondsSliderLabel = uilabel(app.AdjustwindowsizePanel);
            app.CorrelationAnalysisWindowsecondsSliderLabel.Text = 'Correlation Analysis Window (seconds)';
            app.CorrelationAnalysisWindowsecondsSlider = uislider(app.AdjustwindowsizePanel);
            app.CorrelationAnalysisWindowsecondsSlider.Limits = [5 30];
            app.CorrelationAnalysisWindowsecondsSlider.MajorTicks = [5 10 15 20 25 30];
            app.CorrelationAnalysisWindowsecondsSlider.Value = 5;
            app.UseacumulativewindowCheckBox = uicheckbox(app.AdjustwindowsizePanel);
            app.UseacumulativewindowCheckBox.Text = 'Use a cumulative window';
            app.UseacumulativewindowCheckBox.Value = true;
            app.ApplyButton = uibutton(app.AdjustwindowsizePanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.GroupIDDropDownLabel = uilabel(app.NeuroARTUIFigure);
            app.GroupIDDropDownLabel.Text = 'Group ID';
            app.GroupIDDropDown = uidropdown(app.NeuroARTUIFigure);
            app.GroupIDDropDown.Items = {'1', '2', '3'};
            app.GroupIDDropDown.Value = '1';
            % Show the figure after all components are created
            app.NeuroARTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realTimeApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeuroARTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuroARTUIFigure)
        end
    end
end